classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        ButtonGroup               matlab.ui.container.ButtonGroup
        VanishPointButton         matlab.ui.control.RadioButton
        RearWindowButton          matlab.ui.control.RadioButton
        RadialLinesCheckBox       matlab.ui.control.CheckBox
        CurrentPositionEditField  matlab.ui.control.EditField
        GridLayout2               matlab.ui.container.GridLayout
        OpenButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % flags
        drawVP_flag     % wether VP is defined and drawed
        drawRW_flag     % wether inner Rectangle(Rear Window) is drawed
        
        InputImage      % documentation of the Image
        % spider mesh
        OuterRect       % specify the four corners of outer rectangle
                        % upleft-upright-downright-downleft
        OuterFixies

        InnerRect
        VanishPoint


    end
    
    properties (Access = public)
        draw_outer_rect     % control the drawing of outer rect
        draw_inner_rect     % control the drawing of inner rect
        draw_vp             % * of Vanish Point
        draw_radial         % * of Radial Lines

        radial_size
        select_process
    end
    
    % specify VanishPoint, InnerRect, OuterRect and RadialLines (function)
    methods (Access = private)
        
        % check if mouse is within axes
        function [new_point, isInAxes] = check_mouse_within_axes(app)
            isInAxes = true;
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if cp(1) < 0 % x-axis
                cp(1) = 0;
                isInAxes = false;
            elseif cp(1) > size(app.InputImage,2)
                cp(1) = size(app.InputImage,2);
                isInAxes = false;
            end
            if cp(2) < 0 % y-axis
                cp(2) = 0;
                isInAxes = false;
            elseif cp(2) > size(app.InputImage,1)
                cp(2) = size(app.InputImage,1);
                isInAxes = false;
            end
            new_point = cp;
        end
        
        % draw the initial vanishpoint and radiallines
        function specify_VanishPoint(app)
            % check wether the VP is drawed
            if app.drawVP_flag
                return
            end
            % check weheter the mouse is within outerrect and plot the selection of VanishPoint
            [~, isInAxes] = app.check_mouse_within_axes();
            if ~isInAxes
                return
            end
            app.draw_vp = drawpoint(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor'};
            ValueArray = {'VanishPoint', 0, 'white'};
            set(app.draw_vp, NameArray, ValueArray);
            app.drawVP_flag = true;
        end

        % draw the inner rectangle
        function specify_RearWindow(app)
            if app.drawRW_flag
                return
            end

            %[~, isInAxes] = app.check_mouse_within_axes();
            %if ~isInAxes
            %    return
            %end
            app.draw_inner_rect = drawrectangle(app.UIAxes);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'FaceSelectable'};
            ValueArray = {'InnerRectangle', 0, 'white', 0, false};
            set(app.draw_inner_rect, NameArray, ValueArray);
            app.drawRW_flag = true;
        end

        % project the radiallines of innerrect on the outerrect
        function CorrespondPoint = correspond_point_to_outer_rect(app, point, compared_point)
            % determine the zone of the point in comparison to the compared
            % point
            x = 0; y = 0;
            if point(1) == compared_point(1)
                if point(2) > compared_point(2)
                    CorrespondPoint = [point(1); 0]; 
                elseif point(2) < compared_point(2)
                    CorrespondPoint = [point(1); size(app.InputImage,2)];
                else
                    CorrespondPoint = point;
                end
            elseif point(1) > compared_point(1)
                phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                theta_1 = atan((compared_point(2)-size(app.InputImage,2))/(compared_point(1)-0));
                theta_2 = atan((compared_point(2)-0)/(compared_point(1)-0));
                if phi < theta_1
                    CorrespondPoint = [x; size(app.InputImage,2)];
                    disp('edge2')
                elseif phi >= theta_1 && phi < theta_2 
                    CorrespondPoint = [0; y];
                    disp('edge3')
                else
                    CorrespondPoint = [x; 0];
                    disp('edge0')
                end
            else
                phi = atan((point(2)-compared_point(2))/(point(1)-compared_point(1)));
                theta_1 = atan((compared_point(2)-0)/(compared_point(1)-size(app.InputImage,1)));
                theta_2 = atan((compared_point(2)-size(app.InputImage,2))/(compared_point(1)-size(app.InputImage,1)));
                if phi < theta_1
                    CorrespondPoint = [x; 0];
                    disp('edge0')
                elseif phi >= theta_1 && phi < theta_2
                    CorrespondPoint = [size(app.InputImage,1), y];
                    disp('edge1')
                else
                    CorrespondPoint = [x; size(app.InputImage,2)];
                    disp('edge2')
                end
            end
        end
        
        function specify_RadialLines(app)
            if ~app.drawVP_flag || ~app.drawRW_flag
                return
            end
            % plot the radial lines
            vp = app.VanishPoint;
            app.draw_radial = [];
            %%% need to be optimized
            for l = 1:length(app.OuterFixies)
                radial_line = [vp app.OuterFixies(:,l)];
                app.draw_radial(l) = drawline(app.UIAxes, 'Position', radial_line', 'InteractionsAllowed', 'none', 'Linewidth', 0.5);
            end

            for m = 1:length(app.InnerRect)
                radial_line = [vp app.InnerRect(:,m)];
                app.draw_radial(l+m) = drawline(app.UIAxes, 'Position', radial_line', 'InteractionsAllowed', 'none', 'Linewidth', 2);
            end
        end

        function specify_OuterRectangle(app)
            app.OuterRect = [[0,0]',[size(app.InputImage,2),0]',[size(app.InputImage,2),size(app.InputImage,1)]',[0,size(app.InputImage,1)]'];
            app.draw_outer_rect = drawrectangle(app.UIAxes, 'Position', [0,0,size(app.InputImage,2),size(app.InputImage,1)]);
            NameArray = {'Label', 'LabelAlpha', 'LabelTextColor', 'FaceAlpha', 'InteractionsAllowed'};
            ValueArray = {'OuterRectangle', 0, 'white', 0, 'none',};
            set(app.draw_outer_rect, NameArray, ValueArray);

            % preparation of radiallines based on outer_fixies
            app.OuterFixies = zeros(2,app.radial_size*length(app.OuterRect));
            n = 1;
            for i = 1:app.radial_size
                for j = 1:length(app.OuterRect)
                    app.OuterFixies(:, n) = [round(i*app.OuterRect(1,j)/app.radial_size); app.OuterRect(2,j)];
                    app.OuterFixies(:, n+1) = [app.OuterRect(1,j); round(i*app.OuterRect(2,j)/app.radial_size)];
                    n = n + 2;
                end
            end
            app.OuterFixies = unique(app.OuterFixies', 'rows')';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            title(app.UIAxes,'');
            xlabel(app.UIAxes,'');
            ylabel(app.UIAxes,'');
            
            app.radial_size = 3;
            app.drawVP_flag = false;
            app.drawRW_flag = false;
            app.select_process = 1;
            app.RadialLinesCheckBox.Value = 0;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path,FilterIndex] = uigetfile('*.*','Select an Image File');
            startupFcn(app);
            if FilterIndex == 0
                return;
            end
            app.InputImage = imread(fullfile(path,file));
            imshow(app.InputImage,'Parent',app.UIAxes);
            app.specify_OuterRectangle();
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            tf = strcmp(app.UIFigure.SelectionType, 'normal');
            if tf == 1 % left click of the mouse
                switch app.select_process
                    case 1
                        app.specify_VanishPoint();
                        set(app.draw_vp,'InteractionsAllowed','all');
                        set(app.draw_inner_rect,'InteractionsAllowed','none')
                    case 2
                        app.specify_RearWindow();
                        set(app.draw_vp,'InteractionsAllowed','none');
                        set(app.draw_inner_rect,'InteractionsAllowed','all')           
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            set(app.CurrentPositionEditField,'Value',sprintf('%.2f, %.2f',app.UIAxes.CurrentPoint(1,1:2)))
            if app.drawVP_flag
                app.VanishPoint = app.draw_vp.Position';
            end
            if app.drawRW_flag
                inner_rect_pos = app.draw_inner_rect.Position;
                app.InnerRect = [[inner_rect_pos(1);inner_rect_pos(2)],...
                                 [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)],...
                                 [inner_rect_pos(1)+inner_rect_pos(3);inner_rect_pos(2)+inner_rect_pos(4)],...
                                 [inner_rect_pos(1);inner_rect_pos(2)+inner_rect_pos(4)]];
            end
            if app.RadialLinesCheckBox.Value
                delete(app.draw_radial)
                app.specify_RadialLines();
                app.correspond_point_to_outer_rect(app.VanishPoint,app.InnerRect(1));
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'VanishPoint')
                app.select_process = 1;
            elseif strcmp(selectedButton.Text,'RearWindow')
                app.select_process = 2;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)

        end

        % Value changed function: RadialLinesCheckBox
        function RadialLinesCheckBoxValueChanged(app, event)
            value = app.RadialLinesCheckBox.Value;
            if value == true
                app.specify_RadialLines();
            else
                delete(app.draw_radial);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrentPositionEditField = uieditfield(app.GridLayout3, 'text');
            app.ButtonGroup = uibuttongroup(app.GridLayout3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RadialLinesCheckBox = uicheckbox(app.ButtonGroup);
            app.RadialLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadialLinesCheckBoxValueChanged, true);
            app.RadialLinesCheckBox.Text = 'RadialLines';
            app.RearWindowButton = uiradiobutton(app.ButtonGroup);
            app.RearWindowButton.Tag = 'R';
            app.RearWindowButton.Text = 'RearWindow';
            app.VanishPointButton = uiradiobutton(app.ButtonGroup);
            app.VanishPointButton.Tag = 'V';
            app.VanishPointButton.Text = 'VanishPoint';
            app.VanishPointButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end